classdef P4_109550184 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        y2EditField       matlab.ui.control.EditField
        y2EditFieldLabel  matlab.ui.control.Label
        x2EditField       matlab.ui.control.EditField
        x2EditFieldLabel  matlab.ui.control.Label
        y1EditField       matlab.ui.control.EditField
        y1EditFieldLabel  matlab.ui.control.Label
        x1EditField       matlab.ui.control.EditField
        x1Label           matlab.ui.control.Label
        DeleteButton      matlab.ui.control.Button
        ChangeButton      matlab.ui.control.Button
        AddButton         matlab.ui.control.Button
        ListBox           matlab.ui.control.ListBox
        UIAxes            matlab.ui.control.UIAxes
    end

    %郭大寧109550184
    properties (Access = private)
        Property % Description
        List = {} %for List Box
        originalList = {} %for Edit Field
        valueList = {} %for Draw Rectangle
        listN = 0 %count 
        chooseID = 0 %choose id in List Box
        x1 %input
        y1 %input
        x2 %input
        y2 %input
    end
    
    methods (Access = private)
        
        function drawRectangle(app)
            cla(app.UIAxes, 'reset'); %clear axes
            for ii = 1:app.listN %draw rectangle
                app.x1 = app.valueList{ii}(1);
                app.y1 = app.valueList{ii}(2);
                app.x2 = app.valueList{ii}(3);
                app.y2 = app.valueList{ii}(4);
                if ii ~= app.chooseID
                    rectangle(app.UIAxes, 'Position',[app.x1 app.y1 (app.x2-app.x1) (app.y2-app.y1)],'EdgeColor','b');  
                end
            end 
            %draw selected rectangle
            if app.chooseID ~= 0
                app.x1 = app.valueList{app.chooseID}(1);
                app.y1 = app.valueList{app.chooseID}(2);
                app.x2 = app.valueList{app.chooseID}(3);
                app.y2 = app.valueList{app.chooseID}(4);
                rectangle(app.UIAxes, 'Position',[app.x1 app.y1 (app.x2-app.x1) (app.y2-app.y1)],'EdgeColor','r','LineWidth',1.5);
            end    
        end

        function positionRectangle(app, id) %rectangle input, use in addButton & changeButton
            app.x1 = str2double(app.x1EditField.Value);
            app.y1 = str2double(app.y1EditField.Value);
            app.x2 = str2double(app.x2EditField.Value);
            app.y2 = str2double(app.y2EditField.Value);
            c = convertStringsToChars("("+num2str(app.x1)+","+num2str(app.y1)+")-("+num2str(app.x2)+","+num2str(app.y2)+")");
            app.List{id} = c; %init value for List Box
            app.originalList{id} = [app.x1, app.y1, app.x2, app.y2]; %for list to Edit Field
            if app.x1 > app.x2 %check x1 x2, swap
                tmp = app.x1;
                app.x1 = app.x2;
                app.x2 = tmp;
            end
            if app.y1 > app.y2 %check y1 y2, swap
                tmp = app.y1;
                app.y1 = app.y2;
                app.y2 = tmp;          
            end
            app.valueList{id} = [app.x1, app.y1, app.x2, app.y2]; %for draw rectangle 
        end
        
        function showEditField(app)
            if app.chooseID ~= 0 %list to Edit Field
                app.x1EditField.Value = num2str(app.originalList{app.chooseID}(1));
                app.y1EditField.Value = num2str(app.originalList{app.chooseID}(2));
                app.x2EditField.Value = num2str(app.originalList{app.chooseID}(3));
                app.y2EditField.Value = num2str(app.originalList{app.chooseID}(4));         
            else
                app.x1EditField.Value = '';
                app.y1EditField.Value = '';
                app.x2EditField.Value = '';
                app.y2EditField.Value = '';
            end
            app.drawRectangle;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            app.chooseID = event.InteractionInformation.Item; 
            app.showEditField;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if ~isempty(app.x1EditField.Value) && ~isempty(app.y1EditField.Value) && ~isempty(app.x2EditField.Value) && ~isempty(app.y2EditField.Value)...
                    && ~isnan(str2double(app.x1EditField.Value)) && ~isnan(str2double(app.y1EditField.Value)) && ~isnan(str2double(app.x2EditField.Value)) && ~isnan(str2double(app.y2EditField.Value))
                app.listN = app.listN + 1;
                app.positionRectangle(app.listN);       
                app.ListBox.Items = app.List;    
                app.chooseID = app.listN; 
                app.ListBox.Value = app.ListBox.Items{app.chooseID};
                app.drawRectangle;           
            end
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            if app.chooseID ~= 0
                if ~isempty(app.x1EditField.Value) && ~isempty(app.y1EditField.Value) && ~isempty(app.x2EditField.Value) && ~isempty(app.y2EditField.Value)...
                        && ~isnan(str2double(app.x1EditField.Value)) && ~isnan(str2double(app.y1EditField.Value)) && ~isnan(str2double(app.x2EditField.Value)) && ~isnan(str2double(app.y2EditField.Value))
                    app.positionRectangle(app.chooseID);
                    app.ListBox.Items = app.List;  
                    app.ListBox.Value = app.ListBox.Items{app.chooseID};
                    app.drawRectangle;
                end
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            if app.chooseID ~= 0
                app.listN = app.listN-1;
                for ii = app.chooseID:app.listN %delete
                    app.List{ii} = app.List{ii+1};
                    app.originalList{ii} = app.originalList{ii+1};
                    app.valueList{ii} = app.valueList{ii+1};   
                end
                app.List = app.List(1:app.listN); %update
                app.originalList = app.originalList(1:app.listN); %update
                app.valueList = app.valueList(1:app.listN); %update
                app.ListBox.Items = app.List;
                if app.listN == 0
                    app.chooseID = 0;
                else
                    if app.chooseID > app.listN
                        app.chooseID = app.listN;
                    end
                    app.ListBox.Value = app.ListBox.Items{app.chooseID};
                end
                app.showEditField;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.x1Label = uilabel(app.UIFigure);
            app.x1Label.Text = 'x1:';
            app.x1EditField = uieditfield(app.UIFigure, 'text');
            app.y1EditFieldLabel = uilabel(app.UIFigure);
            app.y1EditFieldLabel.Text = 'y1:';
            app.y1EditField = uieditfield(app.UIFigure, 'text');
            app.x2EditFieldLabel = uilabel(app.UIFigure);
            app.x2EditFieldLabel.Text = 'x2:';
            app.x2EditField = uieditfield(app.UIFigure, 'text');
            app.y2EditFieldLabel = uilabel(app.UIFigure);
            app.y2EditFieldLabel.Text = 'y2:';
            app.y2EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4_109550184

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end