classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Label                matlab.ui.control.Label
        ZoomResettingButton  matlab.ui.control.Button
        ZoomoutButton        matlab.ui.control.Button
        ZoominButton         matlab.ui.control.Button
        Panel                matlab.ui.container.Panel
        UIAxes               matlab.ui.control.UIAxes
        BSlider              matlab.ui.control.Slider
        BSliderLabel         matlab.ui.control.Label
        GSlider              matlab.ui.control.Slider
        GSliderLabel         matlab.ui.control.Label
        RSlider              matlab.ui.control.Slider
        RSliderLabel         matlab.ui.control.Label
        ResetButton          matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        ListBoxLabel         matlab.ui.control.Label
        FileButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
        folder 
        im0
        zoom = 1
    end
    
    methods (Access = private)
        
        function select_file(app)
            if ~isempty(app.ListBox.Value)
                file = app.ListBox.Value;       
                img_path = strcat(app.folder, '/', file);
                app.im0 = imread(img_path); 
                app.show_image;
            end
        end

        function show_image(app)
            app.RSlider.Enable = 1;
            app.GSlider.Enable = 1;
            app.BSlider.Enable = 1;
            
            im = app.im0;
            r = 2^(-app.RSlider.Value);
            g = 2^(-app.GSlider.Value);
            b = 2^(-app.BSlider.Value);
            im = im2double(im);
            
            im(:,:,1) = im(:,:,1).^r;
            im(:,:,2) = im(:,:,2).^g;
            im(:,:,3) = im(:,:,3).^b;
            
            app.Panel.Scrollable = true;
            app.Panel.AutoResizeChildren = false;
            imsize = size(im);
            w = imsize(1);
            h = imsize(2);
            
            app.UIAxes.Position=[0 0 w h];
            im = imresize(im,app.zoom,'nearest');
            imsize = size(im);
            w = imsize(1);
            h = imsize(2);
            app.Label.Text = sprintf("%d %d", w,h);
            image(app.UIAxes,im);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RSlider.Enable = 0;
            app.GSlider.Enable = 0;
            app.BSlider.Enable = 0;
        end

        % Button pushed function: FileButton
        function FileButtonPushed(app, event)
            path = uigetdir();
            if path == 0
                   return
            end  
            app.folder = path;
            FolderInfo = dir(fullfile(app.folder,'*.jpg')); 
            app.ListBox.Items = {FolderInfo(:).name};
            app.select_file;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.RSlider.Value = 0;
            app.GSlider.Value = 0;
            app.BSlider.Value = 0;
            app.show_image;
        end

        % Value changed function: ListBox
        function ValueChanged(app, event)
            value = app.ListBox.Value;
            app.select_file;
        end

        % Value changed function: RSlider
        function RSliderValueChanged(app, event)
            value = app.RSlider.Value;
            app.show_image;
        end

        % Value changed function: GSlider
        function GSliderValueChanged(app, event)
            value = app.GSlider.Value;
            app.show_image;
        end

        % Value changed function: BSlider
        function BSliderValueChanged(app, event)
            value = app.BSlider.Value;
            app.show_image;
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            app.zoom = app.zoom * 2;
            app.show_image;
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            app.zoom = app.zoom / 2;
            app.show_image;
        end

        % Button pushed function: ZoomResettingButton
        function ZoomResettingButtonPushed(app, event)
            app.zoom = 1;
            app.show_image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileButton = uibutton(app.UIFigure, 'push');
            app.FileButton.ButtonPushedFcn = createCallbackFcn(app, @FileButtonPushed, true);
            app.FileButton.Text = 'File';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RSliderLabel = uilabel(app.UIFigure);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.UIFigure);
            app.RSlider.Limits = [-2 2];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.GSliderLabel = uilabel(app.UIFigure);
            app.GSliderLabel.Text = 'G';
            app.GSlider = uislider(app.UIFigure);
            app.GSlider.Limits = [-2 2];
            app.GSlider.ValueChangedFcn = createCallbackFcn(app, @GSliderValueChanged, true);
            app.BSliderLabel = uilabel(app.UIFigure);
            app.BSliderLabel.Text = 'B';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [-2 2];
            app.BSlider.ValueChangedFcn = createCallbackFcn(app, @BSliderValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ZoominButton = uibutton(app.UIFigure, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.ZoomoutButton = uibutton(app.UIFigure, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            app.ZoomResettingButton = uibutton(app.UIFigure, 'push');
            app.ZoomResettingButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomResettingButtonPushed, true);
            app.ZoomResettingButton.Text = 'Zoom Resetting';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end